classdef RawPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DesignedbyPNJie20250421V00Label  matlab.ui.control.Label
        PathBrowseButton          matlab.ui.control.Button
        BatchProcessingButton     matlab.ui.control.Button
        OutputpathEditField       matlab.ui.control.EditField
        OutputpathEditFieldLabel  matlab.ui.control.Label
        RAWFILEPLOTTOOLLabel      matlab.ui.control.Label
        Ig_LCheckBox              matlab.ui.control.CheckBox
        Id_LCheckBox              matlab.ui.control.CheckBox
        Vgs_LCheckBox             matlab.ui.control.CheckBox
        Vds_LCheckBox             matlab.ui.control.CheckBox
        Ig_HCheckBox              matlab.ui.control.CheckBox
        Id_HCheckBox              matlab.ui.control.CheckBox
        Vgs_HCheckBox             matlab.ui.control.CheckBox
        Vds_HCheckBox             matlab.ui.control.CheckBox
        PLOTButton                matlab.ui.control.Button
        FilePathEditField         matlab.ui.control.EditField
        FilePathEditFieldLabel    matlab.ui.control.Label
        RawFile4DropDown          matlab.ui.control.DropDown
        RawFile4DropDownLabel     matlab.ui.control.Label
        RawFile3DropDown          matlab.ui.control.DropDown
        RawFile3DropDownLabel     matlab.ui.control.Label
        RawFile2DropDown          matlab.ui.control.DropDown
        RawFile2DropDownLabel     matlab.ui.control.Label
        RawFile1DropDown          matlab.ui.control.DropDown
        RawFile1DropDownLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        SelectedFiles = {}; % Description
        variables = {};% = {'Vin', 'Ig_L'}; Call this parameter by using app.variables in function
        outputPath char   % 存储用户选择的输出路径
        inputPath char %输入路径

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FilePathEditField
        function FilePathEditFieldValueChanged(app, event)
        file_path = app.FilePathEditField.Value;
        raw_name = dir(fullfile(file_path, '*.raw'));
        raw_name = [{'None'},{raw_name.name}];
        app.RawFile1DropDown.Items = raw_name;
        app.RawFile2DropDown.Items = raw_name;
        app.RawFile3DropDown.Items = raw_name;
        app.RawFile4DropDown.Items = raw_name;
        app.inputPath = file_path;
        end

        % Value changed function: RawFile2DropDown
        function RawFile2DropDownValueChanged(app, event)
            value = app.RawFile2DropDown.Value;
            if ~strcmp(value, 'None')
                app.SelectedFiles{2} = value;
            else
                app.SelectedFiles{2} = []; % 清空未选择任何文件时的
            end
            app.SelectedFiles = app.SelectedFiles(~cellfun('isempty', app.SelectedFiles));
            % disp('Selected files updated.');
            % disp(app.SelectedFiles);
            
        end

        % Value changed function: RawFile3DropDown
        function RawFile3DropDownValueChanged(app, event)
            value = app.RawFile3DropDown.Value;
            if ~strcmp(value, 'None')
                app.SelectedFiles{3} = value;
            else
                app.SelectedFiles{3} = ''; % 清空未选择任何文件时的值
            end
            app.SelectedFiles = app.SelectedFiles(~cellfun('isempty', app.SelectedFiles));%delete the empty value "None" in the cell 
            % disp('Selected files updated.');
            % disp(app.SelectedFiles);
        end

        % Value changed function: RawFile4DropDown
        function RawFile4DropDownValueChanged(app, event)
            value = app.RawFile4DropDown.Value;
            if ~strcmp(value, 'None')
                app.SelectedFiles{4} = value;
            else
                app.SelectedFiles{4} = ''; % 清空未选择任何文件时的值
            end
            app.SelectedFiles = app.SelectedFiles(~cellfun('isempty', app.SelectedFiles));
            % disp('Selected files updated.');
            % disp(app.SelectedFiles);
        end

        % Value changed function: RawFile1DropDown
        function RawFile1DropDownValueChanged(app, event)
            value = app.RawFile1DropDown.Value;
            if ~strcmp(value, 'None')
                app.SelectedFiles{1} = value;
            else
                app.SelectedFiles{1} = ''; % 清空未选择任何文件时的值
            end
            app.SelectedFiles = app.SelectedFiles(~cellfun('isempty', app.SelectedFiles));
            % disp('Selected files updated.');
            % disp(app.SelectedFiles);
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
        % if isempty(app.SelectedFiles)
        % uialert(app.UIFigure, '请至少选择一个文件！', '参数错误');
        % return;
        % elseif isempty(app.SelectedVariables)
        % uialert(app.UIFigure, '请至少选择一个变量！', '参数错误');
        % return;
        % end
        %  % 调用外部函数 RAW_plot
        % try
        % RAW_plot(app.SelectedFiles, app.variables);
        %  catch ME
        % uialert(app.UIFigure, ...
        %     sprintf('绘图失败：%s', ME.message), ...
        %     '运行错误');
        % end
        % 显示一个进度对话框
        %Variables choose
                app.variables = {};  
        
        if app.Vds_HCheckBox.Value
            app.variables{end+1} = 'Vds_H';
        end
        if app.Vds_LCheckBox.Value
            app.variables{end+1} = 'Vds_L';
        end
        if app.Vgs_HCheckBox.Value
            app.variables{end+1} = 'Vgs_H';
        end
        if app.Vgs_LCheckBox.Value
            app.variables{end+1} = 'Vgs_L';
        end
        if app.Id_HCheckBox.Value
            app.variables{end+1} = 'Id_H';
        end
        if app.Id_LCheckBox.Value
            app.variables{end+1} = 'Id_L';
        end
        if app.Ig_HCheckBox.Value
            app.variables{end+1} = 'Ig_H';
        end
        if app.Ig_LCheckBox.Value
            app.variables{end+1} = 'Ig_L';
        end

        %RAW plot
        if isempty(app.SelectedFiles)
        uialert(app.UIFigure, '请至少选择一个文件！', '参数错误');
        return;
        elseif isempty(app.variables)
        uialert(app.UIFigure, '请至少选择一个变量！', '参数错误');
        return;
        end
        dlg = uiprogressdlg(app.UIFigure, ...
            'Title', '处理中', ...
            'Message', '正在绘制图形，请稍候...', ...
            'Indeterminate', 'on'); % 设置为不确定模式（不断旋转）

        try
            % 调用耗时函数
            RAW_plot(app.inputPath, app.SelectedFiles, app.variables);

            % 关闭进度条并提示完成
            close(dlg);
            uialert(app.UIFigure, '处理完成！', '提示');

        catch ME
            % 发生错误时关闭进度条并显示错误
            close(dlg);
            uialert(app.UIFigure, ME.message, '错误');
        end
        end

        % Value changed function: Vds_HCheckBox
        function Vds_HCheckBoxValueChanged(app, event)


        % % 显示选中的variables（可选）
        % disp('当前选中的变量:');
        % disp(app.variables);
        end

        % Value changed function: OutputpathEditField
        function OutputpathEditFieldValueChanged(app, event)
            value = app.OutputpathEditField.Value;
            app.outputPath = value;
        end

        % Button pushed function: BatchProcessingButton
        function BatchProcessingButtonPushed(app, event)
            % 检查输出路径是否有效
            if isempty(app.outputPath) 
                uialert(app.UIFigure, '请先选择有效的输出路径！', '错误');
                return;
            end

            % 更新状态标签
             dlg = uiprogressdlg(app.UIFigure, ...
            'Title', '处理中', ...
            'Message', '正在处理数据，请稍候...', ...
            'Indeterminate', 'on'); % 设置为不确定模式（不断旋转）

            try
                data_summary = DPTBatchProcessing(app.inputPath); % 假设这是您的函数

                % 写入 Excel 文件
                writetable(data_summary, app.outputPath, ...
                    'Sheet', 'DPT_Summary', 'WriteMode', 'overwritesheet');

                % 更新状态并提示成功
                close(dlg);
                uialert(app.UIFigure, '处理完成！', '提示');
            catch ME
               % 发生错误时关闭进度条并显示错误
            close(dlg);
            uialert(app.UIFigure, ME.message, '错误');
            end
        end

        % Button pushed function: PathBrowseButton
        function PathBrowseButtonPushed(app, event)
            selectedPath = uigetdir('请选择输出路径'); % 'uigetdir' 是 MATLAB 自带的弹窗
            if selectedPath ~= 0 % 用户选择了有效路径
                app.inputPath = selectedPath;
                app.FilePathEditField.Value = selectedPath; % 更新编辑框显示
            end
             file_path = app.FilePathEditField.Value;
             raw_name = dir(fullfile(file_path, '*.raw'));
             raw_name = [{'None'},{raw_name.name}];
             app.RawFile1DropDown.Items = raw_name;
             app.RawFile2DropDown.Items = raw_name;
             app.RawFile3DropDown.Items = raw_name;
             app.RawFile4DropDown.Items = raw_name;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RawFile1DropDownLabel = uilabel(app.UIFigure);
            app.RawFile1DropDownLabel.Text = 'Raw File 1';
            app.RawFile1DropDown = uidropdown(app.UIFigure);
            app.RawFile1DropDown.ValueChangedFcn = createCallbackFcn(app, @RawFile1DropDownValueChanged, true);
            app.RawFile2DropDownLabel = uilabel(app.UIFigure);
            app.RawFile2DropDownLabel.Text = 'Raw File 2';
            app.RawFile2DropDown = uidropdown(app.UIFigure);
            app.RawFile2DropDown.ValueChangedFcn = createCallbackFcn(app, @RawFile2DropDownValueChanged, true);
            app.RawFile3DropDownLabel = uilabel(app.UIFigure);
            app.RawFile3DropDownLabel.Text = 'Raw File 3';
            app.RawFile3DropDown = uidropdown(app.UIFigure);
            app.RawFile3DropDown.ValueChangedFcn = createCallbackFcn(app, @RawFile3DropDownValueChanged, true);
            app.RawFile4DropDownLabel = uilabel(app.UIFigure);
            app.RawFile4DropDownLabel.Text = 'Raw File 4';
            app.RawFile4DropDown = uidropdown(app.UIFigure);
            app.RawFile4DropDown.ValueChangedFcn = createCallbackFcn(app, @RawFile4DropDownValueChanged, true);
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.ValueChangedFcn = createCallbackFcn(app, @FilePathEditFieldValueChanged, true);
            app.FilePathEditField.Placeholder = 'raw file path';
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.Vds_HCheckBox = uicheckbox(app.UIFigure);
            app.Vds_HCheckBox.ValueChangedFcn = createCallbackFcn(app, @Vds_HCheckBoxValueChanged, true);
            app.Vds_HCheckBox.Text = 'Vds_H';
            app.Vgs_HCheckBox = uicheckbox(app.UIFigure);
            app.Vgs_HCheckBox.Text = 'Vgs_H';
            app.Id_HCheckBox = uicheckbox(app.UIFigure);
            app.Id_HCheckBox.Text = 'Id_H';
            app.Ig_HCheckBox = uicheckbox(app.UIFigure);
            app.Ig_HCheckBox.Text = 'Ig_H';
            app.Vds_LCheckBox = uicheckbox(app.UIFigure);
            app.Vds_LCheckBox.Text = 'Vds_L';
            app.Vgs_LCheckBox = uicheckbox(app.UIFigure);
            app.Vgs_LCheckBox.Text = 'Vgs_L';
            app.Id_LCheckBox = uicheckbox(app.UIFigure);
            app.Id_LCheckBox.Text = 'Id_L';
            app.Ig_LCheckBox = uicheckbox(app.UIFigure);
            app.Ig_LCheckBox.Text = 'Ig_L';
            app.RAWFILEPLOTTOOLLabel = uilabel(app.UIFigure);
            app.RAWFILEPLOTTOOLLabel.Text = 'RAW FILE PLOT TOOL';
            app.OutputpathEditFieldLabel = uilabel(app.UIFigure);
            app.OutputpathEditFieldLabel.Text = 'Output path';
            app.OutputpathEditField = uieditfield(app.UIFigure, 'text');
            app.OutputpathEditField.ValueChangedFcn = createCallbackFcn(app, @OutputpathEditFieldValueChanged, true);
            app.OutputpathEditField.Placeholder = 'eg. C:\Users\PNJIE\Desktop\test\4Pinvs3Pin.xlsx';
            app.BatchProcessingButton = uibutton(app.UIFigure, 'push');
            app.BatchProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @BatchProcessingButtonPushed, true);
            app.BatchProcessingButton.Text = 'BatchProcessing';
            app.PathBrowseButton = uibutton(app.UIFigure, 'push');
            app.PathBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @PathBrowseButtonPushed, true);
            app.PathBrowseButton.Text = 'Path Browse';
            app.DesignedbyPNJie20250421V00Label = uilabel(app.UIFigure);
            app.DesignedbyPNJie20250421V00Label.Text = 'Designed by PNJie   2025/04/21 V0.0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RawPlot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end